classdef Xenon_RealTime_Calibration_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        IfnosignificantchangestosystemsincelastscancanuseLabel  matlab.ui.control.Label
        LastUsed129XeFrequencyEditField  matlab.ui.control.NumericEditField
        LastUsed129XeFrequencyEditFieldLabel  matlab.ui.control.Label
        LastComputedLabel               matlab.ui.control.Label
        Hfrequencyis1000HzfromexpectedLabel  matlab.ui.control.Label
        Estimated129XeFrequencyLabel_2  matlab.ui.control.Label
        Estimated129XeFrequencyEditField  matlab.ui.control.NumericEditField
        Estimated129XeFrequencyLabel    matlab.ui.control.Label
        HFrequencyEditField             matlab.ui.control.NumericEditField
        HFrequencyEditFieldLabel        matlab.ui.control.Label
        ProtocolNameEditField           matlab.ui.control.EditField
        ProtocolNameEditFieldLabel      matlab.ui.control.Label
        DateAcquiredEditField           matlab.ui.control.EditField
        DateAcquiredEditFieldLabel      matlab.ui.control.Label
        SelectedDataFileisInvalidLabel  matlab.ui.control.Label
        DataSelectionLabel              matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        DataSelectionButtonGroup        matlab.ui.container.ButtonGroup
        ManuallySelectButton            matlab.ui.control.RadioButton
        AutoFindButton                  matlab.ui.control.RadioButton
        BrowseButton                    matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        LastAnalylzedLabel              matlab.ui.control.Label
        CalibrationOutputLabel          matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        Typicalvaluesarebetween600and800Label  matlab.ui.control.Label
        RBCBarrierRatioshouldbeintherange0107CheckyourdataLabel  matlab.ui.control.Label
        EchoTimeisoutsidetherange045050Using047msLabel  matlab.ui.control.Label
        TrueRefPowerSequenceSpecialLabel_2  matlab.ui.control.Label
        GasCenterFrequencyOptionsAdjustmentsEditFieldLabel_2  matlab.ui.control.Label
        RBCBarrierRatioEditField        matlab.ui.control.EditField
        RBCBarrierRatioLabel            matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        EchoTimeContrastTabGasExchangeSequenceOnlyEditField  matlab.ui.control.EditField
        EchoTimeContrastTabGasExchangeSequenceOnlyEditFieldLabel  matlab.ui.control.Label
        DissolvedSignalisPresentCheckBox  matlab.ui.control.CheckBox
        HzLabel                         matlab.ui.control.Label
        GasCenterFrequencyOptionsAdjustmentsEditField  matlab.ui.control.EditField
        VLabel                          matlab.ui.control.Label
        DecayofGasSignalshowsExponentialDecayCheckBox  matlab.ui.control.CheckBox
        TrueRefPowerSequenceSpecialEditField  matlab.ui.control.EditField
        QCandParameterSettingsLabel     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Default_Path = '\\10.19.3.61\ku3T-temp\Ivina\0_Calibration';
        Data_File;
        Sequence; % Sequence Name from Twix
        twix_obj;
        freq_target;
        te90;
        flip_angle;
        FlipTarget;
        RBC2Bar;
        NewRefV;
        VRef;
        okflag;
    end
   
    methods (Access = private)
        %Function to check if data is correct
        function check_file(app)
            app.twix_obj = DataImport.mapVBVD(app.Data_File);
            app.Sequence = app.twix_obj.hdr.Config.SequenceFileName;
            scanDate = app.twix_obj.hdr.Phoenix.tReferenceImage0; 
            scanDate = strsplit(scanDate,'.');
            scanDate = scanDate{end};
            scanDateStr = [scanDate(1:4),'-',scanDate(5:6),'-',scanDate(7:8)];
            app.DateAcquiredEditField.Value = scanDateStr;
            app.ProtocolNameEditField.Value = app.twix_obj.hdr.Config.SequenceDescription;
            
            if ~contains(app.Sequence,'XeCal_ShortTR') && ~contains(app.Sequence,'fid_xe_calibration')
                app.SelectedDataFileisInvalidLabel.Visible = 'on';
                app.okflag = 0;
            %    app.AnalyzeButton.Enable = 'off';
            else
                app.okflag = 1;
                app.SelectedDataFileisInvalidLabel.Visible = 'off';
            %    app.AnalyzeButton.Enable = 'on';
            end
        end
        %Function to automatically find most recent Calibration File
        function autofind_calfile(app)
            %% Automatically map to the network drive if we fail to load the data.
            
            files = dir(app.Default_Path);
            if isempty(files)
                mapsuccess = system('net use Y: \\10.19.3.61\ku3T-temp skyra!@#$1 /user:skyra /persistent:yes');
                files = dir(app.Default_Path);
            end
            

            % files = dir(app.Default_Path);
            %get rid of . and .. files
            files(1:2) = [];
            datenumbers = zeros(1,length(files));
            for i = 1:length(files)
                datenumbers(i) = datenum(files(i).date);
            end
            %Sometimes, multiple files have the same datestr, so need
            %to make sure we get the right one
            latest_time = max(datenumbers);
            latest_file = find(datenumbers==latest_time);
            measnum = zeros(size(latest_file));
            count = 1;
            for i = latest_file
                measnum(count) = str2double(files(i).name(9:13));
                count = count + 1;
            end
            [~,last_scan] = max(measnum);
            correct_index = latest_file(last_scan);
            app.Data_File = fullfile(app.Default_Path,files(correct_index).name);
        end
        
        function analyze_cal(app)
            te = app.twix_obj.hdr.Phoenix.alTE{1}; 
            dwell_time = app.twix_obj.hdr.MeasYaps.sRXSPEC.alDwellTime{1,1};  % appears most robust and generic
            dwell_time=dwell_time*1E-9; 
            seq_version = app.twix_obj.hdr.Dicom.tProtocolName;
            seq_version = str2double(seq_version((end-3):end));
            if seq_version < 2402
                freq = app.twix_obj.hdr.Dicom.lFrequency;  % seems to work in all sequences
            else
                freq = app.twix_obj.hdr.Phoenix.sWipMemBlock.alFree{45};
            end
            freq_offset = app.twix_obj.hdr.MeasYaps.sWipMemBlock.alFree{5};
            chem_shift = freq_offset/freq * 1e6;

            Rfreq_guess = freq*218*1e-6 - freq*chem_shift*1e-6;
            Mfreq_guess = freq*197*1e-6 - freq*chem_shift*1e-6;
            Gfreq_guess = -freq_offset;

            %nFids = app.twix_obj.hdr.Config.NRepMeas;
            app.FlipTarget = app.twix_obj.hdr.MeasYaps.adFlipAngleDegree{1};
            %nPts = app.twix_obj.hdr.Config.VectorSize;  % present in cal and FID but not imaging sequences.
            theFID = squeeze(double(app.twix_obj.image()));
            if contains(app.Sequence,'XeCal_ShortTR')
                app.VRef = app.twix_obj.hdr.Phoenix.sWipMemBlock.adFree{5};
                tot_nDis = app.twix_obj.hdr.Phoenix.sWipMemBlock.alFree{2};
                nCal = app.twix_obj.hdr.Phoenix.sWipMemBlock.alFree{7};
                nDis = tot_nDis;
                % parse out the various fids - dissolved, gas, and calibration
                disData = theFID(:,1:nDis); % all dissolved data
                gasData = theFID(:,end-nCal+1);
                disData_avg = mean(disData,2);  % average of all dissolved
                calData = theFID(:,end-nCal+1:end); % the data left for flip angle calculations.
                t = double((0:(length(disData)-1))*dwell_time');
            else
                app.VRef = app.twix_obj.hdr.Phoenix.sWipMemBlock.alFree{1};
                if str2double(app.twix_obj.hdr.Config.SequenceFileName((end-3):end)) > 2200
                    nDis = app.twix_obj.hdr.Phoenix.sWipMemBlock.alFree{3};
                    nCal = app.twix_obj.hdr.Phoenix.sWipMemBlock.alFree{4};
                    disData = theFID(:,2:(nDis+1)); % all dissolved data
                    gasData = theFID(:,end-nCal+1);
                else
                    nDis = app.twix_obj.hdr.Phoenix.sWipMemBlock.alFree{2};
                    nCal = app.twix_obj.hdr.Phoenix.sWipMemBlock.alFree{3};
                    disData = theFID(:,1:(nDis)); % all dissolved data
                    gasData = theFID(:,end-nCal+1);
                end
                nAvg = 100;
                nSkip = 100;
                
                nDis1 = nDis - nSkip; % if skipping first dissolved FIDs
                
                if nAvg > nDis
                   % fprintf('\n Requested averages exceeds available; using %0.0f\n',nDis);
                    disData1 = theFID(:,nSkip+1:nDis); % all available dissolved data with skipping
                else
                disData1 = theFID(:,nSkip+1:(nSkip+1+nAvg)); % requested averages of dissolved
                end %if
                disData1_avg = mean(disData,2);  % average of all dissolved
                disData_avg = mean(disData1,2);  % average of dissolved after skipping
                calData = theFID(:,end-nCal+1:end); % the data left for flip angle calculations.
                t = double((0:(length(disData)-1))*dwell_time');
            end
            %Fit Gas
            gasfitObj = Spectroscopy.NMR_TimeFit(gasData,t,1e-4,-84,30,0,0,10000);
            gasfitObj.fitTimeDomainSignal();
            %Fit Dissolved
            disfitObj = Spectroscopy.NMR_TimeFit_v(disData_avg,t,[1 1 1],[Rfreq_guess Mfreq_guess  Gfreq_guess],[250 200 30],[0 200 0],[0 0 0],0,length(t)); % first widths lorenzian, 2nd are gauss
            disfitObj = disfitObj.fitTimeDomainSignal();
            disfitFinal = dwell_time*fftshift(fft(disfitObj.calcComponentTimeDomainSignal(t),[],1),1);
            
            app.RBC2Bar = disfitObj.area(1)/disfitObj.area(2);
            
            %Get Target Freq
            app.freq_target = freq + gasfitObj.freq(1);
            
            deltaPhase = disfitObj.phase(2)-disfitObj.phase(1); % RBC-barrier phase diff in cal spectrum
            deltaPhase = mod(abs(deltaPhase),180); % deal with wrap around, but also negative phase
            deltaF = abs(disfitObj.freq(2)-disfitObj.freq(1)); % absolute RBC-barrier freq difference
            deltaTe90= (90-deltaPhase)/(360*deltaF); % how far off are we?
            app.te90 =(te + deltaTe90*1e6)/1000; % in msec
            
            % Find Amplitudes faster by using max amplitudes from each FID in calibration
            flipCalAmps = max(abs(calData));

            % calculate flip angle
            fitfunct = @(coefs,xdata)coefs(1)*cos(coefs(2)).^(xdata-1)+coefs(3);   % cos theta decay
            guess(1)=max(flipCalAmps);
            guess(2)=12*pi/180;       % just guess 10 degrees
            guess(3)=0;
            xdata=1:length(flipCalAmps);
            ydata = flipCalAmps;
            
            fitoptions = optimoptions('lsqcurvefit','Display','off');
            [fitparams,resnorm,residual,exitflag,output,lambda,jacobian]  = lsqcurvefit(fitfunct,guess,xdata,ydata,[],[],fitoptions);
            ci = nlparci(fitparams,residual,jacobian);  % returns 95% conf intervals on fitparams by default
            param_err=fitparams-ci(:,1)';
            app.flip_angle=abs(fitparams(2)*180/pi);
            flip_err=param_err(2)*180/pi;
            
            %Get Final Flip Angle
            VRefScaleFactor=app.FlipTarget/app.flip_angle; % How much should Ref Voltage Be Scaled?
            app.NewRefV = app.VRef*VRefScaleFactor;
            
            %Plot Decay of Gas Signal
            plot(app.UIAxes,xdata,ydata,'bo','MarkerFaceColor','b');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,xdata,fitfunct(fitparams,xdata),'-r');
            hold(app.UIAxes,'off');
            legend(app.UIAxes,'Acquired','Fit');
            xlabel(app.UIAxes,'Frame Number');
            ylabel(app.UIAxes,'Magnitude');
            %PJN - Show Ref Voltage instead of Pulse Voltage
            %a=sprintf('Flip Cal: V_p = %0.1f yields flip = %0.1f±%0.1f%c', rf_amp2,flip_angle,flip_err,char(176));
            a=sprintf('Flip Cal: V_p = %0.1f yields flip = %0.1f±%0.1f%c', app.VRef,app.flip_angle,flip_err,char(176));
            title(app.UIAxes,a); % note degree symbol is char(176)
            
            %Plot Dissolved Spectrum
            plot(app.UIAxes_2,disfitObj.f, abs(disfitObj.spectralDomainSignal),'k')%mag
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,disfitObj.f,abs(sum(disfitFinal,2)),'Color',[0 0 1 0.33],'LineWidth',3)%mag
            hold(app.UIAxes_2,'off');
            title(app.UIAxes_2,['Dissolved Phase Spectra and Fit: RBC/Barrier = ',num2str(disfitObj.area(1)/disfitObj.area(2))])
            set(app.UIAxes_2,'Xdir','reverse')
            xlabel(app.UIAxes_2,'Frequency (Hz)')
            ylabel(app.UIAxes_2,'NMR Signal (a.u.)')
            
            %Show when things were last analyzed so that there is no
            %confusion
            app.LastAnalylzedLabel.Text = ['Last Analyzed ' datestr(datetime)];
        end
        
        
        function reset_app(app)
            app.DecayofGasSignalshowsExponentialDecayCheckBox.Value = 0;
            app.DissolvedSignalisPresentCheckBox.Value = 0;
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            app.TrueRefPowerSequenceSpecialEditField.Visible = 'off';
            app.VLabel.Visible = 'off';
            app.GasCenterFrequencyOptionsAdjustmentsEditField.Visible = 'off';
            app.HzLabel.Visible = 'off';
            title(app.UIAxes,' ');
            title(app.UIAxes_2,' ');
            app.Data_File = [];
            app.TrueRefPowerSequenceSpecialEditField.Value = ' ';
            app.GasCenterFrequencyOptionsAdjustmentsEditField.Value = ' ';
            app.GasCenterFrequencyOptionsAdjustmentsEditFieldLabel_2.Visible = 'off';
            app.TrueRefPowerSequenceSpecialLabel_2.Visible = 'off';
            app.NewRefV = 0;
            app.freq_target = 0;
            app.EchoTimeContrastTabGasExchangeSequenceOnlyEditField.Visible = 'off';
            app.EchoTimeContrastTabGasExchangeSequenceOnlyEditFieldLabel.Visible = 'off';
            app.msLabel.Visible = 'off';
            app.RBCBarrierRatioEditField.Visible = 'off';
            app.RBCBarrierRatioLabel.Visible = 'off';
            app.EchoTimeisoutsidetherange045050Using047msLabel.Visible = 'off';
            app.RBCBarrierRatioshouldbeintherange0107CheckyourdataLabel.Visible = 'off';
            app.Typicalvaluesarebetween600and800Label.Visible = 'off';
            app.te90 = 0;
            app.RBC2Bar = 0;
            app.Estimated129XeFrequencyEditField.Value = app.HFrequencyEditField.Value/3.615290338;
            app.Hfrequencyis1000HzfromexpectedLabel.Visible = 'off';
            app.LastComputedLabel.Text = 'Last Computed XXXXXXXXXX';
            app.LastAnalylzedLabel.Text = 'Last Analylzed XXXXXXXXXX';
            get_last_freq(app);
        end
        
        function get_last_freq(app)
            files = dir(app.Default_Path);
            if isempty(files)
                mapsuccess = system('net use Z: \\10.19.3.34\ku3T-temp skyra!@#$ /user:skyra /persistent:yes');
                files = dir(app.Default_Path);
            end
            files(1:2) = [];
            datenumbers = zeros(1,length(files));
            for i = 1:length(files)
                datenumbers(i) = datenum(files(i).date);
            end
            %Sometimes, multiple files have the same datestr, so need
            %to make sure we get the right one
            latest_time = max(datenumbers);
            latest_file = find(datenumbers==latest_time);
            measnum = zeros(size(latest_file));
            count = 1;
            for i = latest_file
                measnum(count) = str2double(files(i).name(9:13));
                count = count + 1;
            end
            [~,last_scan] = max(measnum);
            correct_index = latest_file(last_scan);
            Last_File = fullfile(app.Default_Path,files(correct_index).name);

            my_twix_obj = DataImport.mapVBVD(Last_File);
            seq_version = my_twix_obj.hdr.Dicom.tProtocolName;
            seq_version = str2double(seq_version((end-3):end));
            if seq_version < 2402
                freq = my_twix_obj.hdr.Dicom.lFrequency;  % seems to work in all sequences
            else
                freq = my_twix_obj.hdr.Phoenix.sWipMemBlock.alFree{45};
            end
            app.LastUsed129XeFrequencyEditField.Value = freq;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            reset_app(app)
            autofind_calfile(app);
            if isfile(app.Data_File)
                check_file(app);
            end
            if app.okflag
                analyze_cal(app);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {609, 609, 609};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {609, 609};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {246, '1x', 363};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Selection changed function: DataSelectionButtonGroup
        function DataSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataSelectionButtonGroup.SelectedObject;
            reset_app(app)
            %if autofind is selected
            if app.AutoFindButton.Value
                app.BrowseButton.Visible = 'off';
                app.UpdateButton.Visible = 'on';
                autofind_calfile(app);
            else
                app.BrowseButton.Visible = 'on';
                app.UpdateButton.Visible = 'off';
                [file,path] = uigetfile('*.dat','Select Calibration File');
                if exist('file')
                    app.Data_File = fullfile(path,file);
                end
            end
            if isfile(app.Data_File)
                check_file(app);
            end
            if app.okflag
                analyze_cal(app);
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            reset_app(app)
            autofind_calfile(app);
            if isfile(app.Data_File)
                check_file(app);
            end
            if app.okflag
                analyze_cal(app);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            reset_app(app)
            [file,path] = uigetfile('*.dat','Select Calibration File');
            app.Data_File = fullfile(path,file);
            if isfile(app.Data_File)
                check_file(app);
            end
            if app.okflag
                analyze_cal(app);
            end
        end

        % Callback function
        function AnalyzeButtonPushed(app, event)
            reset_app(app) % Legacy. Analyze button removed in the current version of the app.
            analyze_cal(app);
        end

        % Value changed function: 
        % DecayofGasSignalshowsExponentialDecayCheckBox
        function DecayofGasSignalshowsExponentialDecayCheckBoxValueChanged(app, event)
            value = app.DecayofGasSignalshowsExponentialDecayCheckBox.Value;
            if value
                app.TrueRefPowerSequenceSpecialEditField.Visible = 'on';
                app.TrueRefPowerSequenceSpecialLabel_2.Visible = 'on';
                app.VLabel.Visible = 'on';
                app.GasCenterFrequencyOptionsAdjustmentsEditField.Visible = 'on';
                app.GasCenterFrequencyOptionsAdjustmentsEditFieldLabel_2.Visible = 'on';
                app.HzLabel.Visible = 'on';
                if app.NewRefV >= 400 && app.NewRefV <= 600
                    app.TrueRefPowerSequenceSpecialEditField.Value = num2str(app.NewRefV,'%.0f');
                    app.Typicalvaluesarebetween600and800Label.Visible = 'off';
                else
                    app.Typicalvaluesarebetween600and800Label.Visible = 'on';
                end
                app.TrueRefPowerSequenceSpecialEditField.Value = num2str(app.NewRefV,'%.0f');
                app.GasCenterFrequencyOptionsAdjustmentsEditField.Value = num2str(app.freq_target,'%.0f');
            else
                app.TrueRefPowerSequenceSpecialEditField.Visible = 'off';
                app.TrueRefPowerSequenceSpecialLabel_2.Visible = 'off';
                app.VLabel.Visible = 'off';
                app.GasCenterFrequencyOptionsAdjustmentsEditField.Visible = 'off';
                app.GasCenterFrequencyOptionsAdjustmentsEditFieldLabel_2.Visible = 'off';
                app.Typicalvaluesarebetween600and800Label.Visible = 'off';
                app.HzLabel.Visible = 'off';
            end
        end

        % Value changed function: DissolvedSignalisPresentCheckBox
        function DissolvedSignalisPresentCheckBoxValueChanged(app, event)
            value = app.DissolvedSignalisPresentCheckBox.Value;
            if value
                app.EchoTimeContrastTabGasExchangeSequenceOnlyEditField.Visible = 'on';
                app.EchoTimeContrastTabGasExchangeSequenceOnlyEditFieldLabel.Visible = 'on';
                app.msLabel.Visible = 'on';
                app.RBCBarrierRatioEditField.Visible = 'on';
                app.RBCBarrierRatioLabel.Visible = 'on';
                if app.te90 >= 0.45 && app.te90 <= 0.5
                    app.EchoTimeContrastTabGasExchangeSequenceOnlyEditField.Value = num2str(app.te90,'%.2f');
                    app.EchoTimeisoutsidetherange045050Using047msLabel.Visible = 'off';
                else
                    app.EchoTimeContrastTabGasExchangeSequenceOnlyEditField.Value = num2str(0.47,'%.2f');
                    app.EchoTimeisoutsidetherange045050Using047msLabel.Visible = 'on';
                end
                if app.RBC2Bar >= 0.1 && app.RBC2Bar <= 0.7
                    app.RBCBarrierRatioEditField.Value = num2str(app.RBC2Bar,'%.2f');
                    app.RBCBarrierRatioshouldbeintherange0107CheckyourdataLabel.Visible = 'off';
                else
                    app.RBCBarrierRatioshouldbeintherange0107CheckyourdataLabel.Visible = 'on';
                end
                %app.RBCBarrierRatioEditField.Value = num2str(app.RBC2Bar,'%.2f');
            else
                app.EchoTimeContrastTabGasExchangeSequenceOnlyEditField.Visible = 'off';
                app.EchoTimeContrastTabGasExchangeSequenceOnlyEditFieldLabel.Visible = 'off';
                app.msLabel.Visible = 'off';
                app.RBCBarrierRatioEditField.Visible = 'off';
                app.RBCBarrierRatioLabel.Visible = 'off';
                app.RBCBarrierRatioshouldbeintherange0107CheckyourdataLabel.Visible = 'off';
                app.EchoTimeisoutsidetherange045050Using047msLabel.Visible = 'off';
                %app.EchoTimeContrastTabGasExchangeSequenceOnlyEditField.Value = num2str(app.te90,'%.2f');
                %app.RBCBarrierRatioEditField.Value = num2str(app.RBC2Bar,'%.2f');
            end
        end

        % Value changed function: HFrequencyEditField
        function HFrequencyEditFieldValueChanged(app, event)
            value = app.HFrequencyEditField.Value;
            expected = 123228827;
            if abs(value-expected)>1000
                app.Hfrequencyis1000HzfromexpectedLabel.Visible = 'on';
            else
                app.Hfrequencyis1000HzfromexpectedLabel.Visible = 'off';
            end
            app.Estimated129XeFrequencyEditField.Value = value/3.615290338;
            app.LastComputedLabel.Text = ['Last Computed ' datestr(datetime)];

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            reset_app(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Visible = 'off';
            app.BrowseButton.Text = 'Browse';
            app.DataSelectionButtonGroup = uibuttongroup(app.LeftPanel);
            app.DataSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSelectionButtonGroupSelectionChanged, true);
            app.DataSelectionButtonGroup.Title = 'Data Selection';
            app.AutoFindButton = uiradiobutton(app.DataSelectionButtonGroup);
            app.AutoFindButton.Text = 'Auto-Find';
            app.AutoFindButton.Value = true;
            app.ManuallySelectButton = uiradiobutton(app.DataSelectionButtonGroup);
            app.ManuallySelectButton.Text = 'Manually Select';
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.DataSelectionLabel = uilabel(app.LeftPanel);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.SelectedDataFileisInvalidLabel = uilabel(app.LeftPanel);
            app.SelectedDataFileisInvalidLabel.Visible = 'off';
            app.SelectedDataFileisInvalidLabel.Text = 'Selected Data File is Invalid';
            app.DateAcquiredEditFieldLabel = uilabel(app.LeftPanel);
            app.DateAcquiredEditFieldLabel.Text = 'Date Acquired';
            app.DateAcquiredEditField = uieditfield(app.LeftPanel, 'text');
            app.DateAcquiredEditField.Editable = 'off';
            app.ProtocolNameEditFieldLabel = uilabel(app.LeftPanel);
            app.ProtocolNameEditFieldLabel.Text = 'Protocol Name';
            app.ProtocolNameEditField = uieditfield(app.LeftPanel, 'text');
            app.ProtocolNameEditField.Editable = 'off';
            app.HFrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.HFrequencyEditFieldLabel.Text = '1H Frequency';
            app.HFrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HFrequencyEditField.ValueDisplayFormat = '%.0f';
            app.HFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @HFrequencyEditFieldValueChanged, true);
            app.HFrequencyEditField.Value = 123228827;
            app.Estimated129XeFrequencyLabel = uilabel(app.LeftPanel);
            app.Estimated129XeFrequencyLabel.Text = {'Estimated 129Xe '; 'Frequency'};
            app.Estimated129XeFrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Estimated129XeFrequencyEditField.ValueDisplayFormat = '%.0f';
            app.Estimated129XeFrequencyEditField.Editable = 'off';
            app.Estimated129XeFrequencyLabel_2 = uilabel(app.LeftPanel);
            app.Estimated129XeFrequencyLabel_2.Text = 'Estimated 129Xe Frequency';
            app.Hfrequencyis1000HzfromexpectedLabel = uilabel(app.LeftPanel);
            app.Hfrequencyis1000HzfromexpectedLabel.Visible = 'off';
            app.Hfrequencyis1000HzfromexpectedLabel.Text = '1H frequency is >1000 Hz from expected';
            app.LastComputedLabel = uilabel(app.LeftPanel);
            app.LastComputedLabel.Text = 'Last Computed XXXXXXXXXX';
            app.LastUsed129XeFrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.LastUsed129XeFrequencyEditFieldLabel.Text = {'Last Used 129Xe '; 'Frequency'};
            app.LastUsed129XeFrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LastUsed129XeFrequencyEditField.ValueDisplayFormat = '%.0f';
            app.LastUsed129XeFrequencyEditField.Editable = 'off';
            app.IfnosignificantchangestosystemsincelastscancanuseLabel = uilabel(app.LeftPanel);
            app.IfnosignificantchangestosystemsincelastscancanuseLabel.Text = {'If no significant changes to system '; 'since last scan, can use:'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.CalibrationOutputLabel = uilabel(app.CenterPanel);
            app.CalibrationOutputLabel.Text = 'Calibration Output';
            app.LastAnalylzedLabel = uilabel(app.CenterPanel);
            app.LastAnalylzedLabel.Text = 'Last Analylzed XXXXXXXXXX';
            app.RightPanel = uipanel(app.GridLayout);
            app.QCandParameterSettingsLabel = uilabel(app.RightPanel);
            app.QCandParameterSettingsLabel.Text = 'QC and Parameter Settings';
            app.TrueRefPowerSequenceSpecialEditField = uieditfield(app.RightPanel, 'text');
            app.TrueRefPowerSequenceSpecialEditField.Editable = 'off';
            app.DecayofGasSignalshowsExponentialDecayCheckBox = uicheckbox(app.RightPanel);
            app.DecayofGasSignalshowsExponentialDecayCheckBox.ValueChangedFcn = createCallbackFcn(app, @DecayofGasSignalshowsExponentialDecayCheckBoxValueChanged, true);
            app.DecayofGasSignalshowsExponentialDecayCheckBox.Text = {'  Decay of Gas Signal '; '  shows Exponential Decay'};
            app.VLabel = uilabel(app.RightPanel);
            app.VLabel.Text = 'V';
            app.GasCenterFrequencyOptionsAdjustmentsEditField = uieditfield(app.RightPanel, 'text');
            app.GasCenterFrequencyOptionsAdjustmentsEditField.Editable = 'off';
            app.HzLabel = uilabel(app.RightPanel);
            app.HzLabel.Text = 'Hz';
            app.DissolvedSignalisPresentCheckBox = uicheckbox(app.RightPanel);
            app.DissolvedSignalisPresentCheckBox.ValueChangedFcn = createCallbackFcn(app, @DissolvedSignalisPresentCheckBoxValueChanged, true);
            app.DissolvedSignalisPresentCheckBox.Text = '  Dissolved Signal is Present';
            app.EchoTimeContrastTabGasExchangeSequenceOnlyEditFieldLabel = uilabel(app.RightPanel);
            app.EchoTimeContrastTabGasExchangeSequenceOnlyEditFieldLabel.Text = {'Echo Time (Contrast Tab):'; '(Gas Exchange Sequence Only)'};
            app.EchoTimeContrastTabGasExchangeSequenceOnlyEditField = uieditfield(app.RightPanel, 'text');
            app.EchoTimeContrastTabGasExchangeSequenceOnlyEditField.Editable = 'off';
            app.msLabel = uilabel(app.RightPanel);
            app.msLabel.Text = 'ms';
            app.RBCBarrierRatioLabel = uilabel(app.RightPanel);
            app.RBCBarrierRatioLabel.Text = 'RBC/Barrier Ratio:';
            app.RBCBarrierRatioEditField = uieditfield(app.RightPanel, 'text');
            app.RBCBarrierRatioEditField.Editable = 'off';
            app.GasCenterFrequencyOptionsAdjustmentsEditFieldLabel_2 = uilabel(app.RightPanel);
            app.GasCenterFrequencyOptionsAdjustmentsEditFieldLabel_2.Text = {'Gas Center Frequency '; '(Options->Adjustments):'};
            app.TrueRefPowerSequenceSpecialLabel_2 = uilabel(app.RightPanel);
            app.TrueRefPowerSequenceSpecialLabel_2.Text = 'True Ref Power (Sequence Special):';
            app.EchoTimeisoutsidetherange045050Using047msLabel = uilabel(app.RightPanel);
            app.EchoTimeisoutsidetherange045050Using047msLabel.Text = {'Echo Time is outside the '; 'range 0.45-0.50. Using 0.47 ms. '};
            app.RBCBarrierRatioshouldbeintherange0107CheckyourdataLabel = uilabel(app.RightPanel);
            app.RBCBarrierRatioshouldbeintherange0107CheckyourdataLabel.Text = {'RBC/Barrier Ratio should be in '; 'the range 0.1-0.7: Check your data.'};
            app.Typicalvaluesarebetween600and800Label = uilabel(app.RightPanel);
            app.Typicalvaluesarebetween600and800Label.Text = {'Typical values are between '; '400 and 600. Consider using '; 'a value within this range.'};
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xenon_RealTime_Calibration_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end